classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        UIAxes                 matlab.ui.control.UIAxes
        PlotButton             matlab.ui.control.Button
        ZoomSliderLabel        matlab.ui.control.Label
        ZoomSlider             matlab.ui.control.Slider
        aEditFieldLabel        matlab.ui.control.Label
        aEditField             matlab.ui.control.NumericEditField
        bEditFieldLabel        matlab.ui.control.Label
        bEditField             matlab.ui.control.NumericEditField
        cEditFieldLabel        matlab.ui.control.Label
        cEditField             matlab.ui.control.NumericEditField
        xliminfEditFieldLabel  matlab.ui.control.Label
        xliminfEditField       matlab.ui.control.NumericEditField
        xlimsupEditFieldLabel  matlab.ui.control.Label
        xlimsupEditField       matlab.ui.control.NumericEditField
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.xliminfEditField.Value=0;
            app.xlimsupEditField.Value=100;
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
         a=app.aEditField.Value;
         b=app.bEditField.Value;
         c=app.cEditField.Value;
         x_lim_inf=app.xliminfEditField.Value;
         x_lim_sup=app.xlimsupEditField.Value;
         x_lim=[x_lim_inf,x_lim_sup];
         
         x=linspace(x_lim(1),x_lim(2),100000);
         y=a*x.^2+b*x+c*ones(1,100000);
         plot(app.UIAxes,x,y);
         xlim(app.UIAxes,[x_lim(1) x_lim(2)]);
         
        end

        % Value changing function: ZoomSlider
        function ZoomSliderValueChanging(app, event)
            changingValue = event.Value;
            a=app.aEditField.Value;
         b=app.bEditField.Value;
         c=app.cEditField.Value;
            zoom=app.ZoomSlider.Value;
             x_lim_inf=app.xliminfEditField.Value;
           x_lim_sup=app.xlimsupEditField.Value;
           x_lim=[x_lim_inf,x_lim_sup]/zoom;
         x=linspace(x_lim(1),x_lim(2),100000);
         y=a*x.^2+b*x+c*ones(1,100000);
         plot(app.UIAxes,x,y);
         xlim(app.UIAxes,[x_lim(1),x_lim(2)]);
         ylim(app.UIAxes,[min(y)-1,max(y)+1]);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Parabole -plot')
            xlabel(app.UIAxes, 'x')
            ylabel(app.UIAxes, 'y')
            app.UIAxes.XLim = [-10000 Inf];
            app.UIAxes.XLimMode = 'manual';
            app.UIAxes.YLim = [-10000 Inf];
            app.UIAxes.YLimMode = 'manual';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ZoomSliderLabel = uilabel(app.UIFigure);
            app.ZoomSliderLabel.Text = 'Zoom';
            app.ZoomSlider = uislider(app.UIFigure);
            app.ZoomSlider.Limits = [1 100];
            app.ZoomSlider.ValueChangingFcn = createCallbackFcn(app, @ZoomSliderValueChanging, true);
            app.ZoomSlider.Value = 1;
            app.aEditFieldLabel = uilabel(app.UIFigure);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.UIFigure, 'numeric');
            app.bEditFieldLabel = uilabel(app.UIFigure);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.UIFigure, 'numeric');
            app.cEditFieldLabel = uilabel(app.UIFigure);
            app.cEditFieldLabel.Text = 'c';
            app.cEditField = uieditfield(app.UIFigure, 'numeric');
            app.xliminfEditFieldLabel = uilabel(app.UIFigure);
            app.xliminfEditFieldLabel.Text = 'xlim-inf';
            app.xliminfEditField = uieditfield(app.UIFigure, 'numeric');
            app.xlimsupEditFieldLabel = uilabel(app.UIFigure);
            app.xlimsupEditFieldLabel.Text = 'xlim-sup';
            app.xlimsupEditField = uieditfield(app.UIFigure, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end